classdef EntranceModelApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        AntennaToEntrancemEditFieldLabel  matlab.ui.control.Label
        AntennaToEntrancemEditField   matlab.ui.control.NumericEditField
        EntranceWidthmEditFieldLabel  matlab.ui.control.Label
        EntranceWidthmEditField       matlab.ui.control.NumericEditField
        AntennaGaindBiEditFieldLabel  matlab.ui.control.Label
        AntennaGaindBiEditField       matlab.ui.control.NumericEditField
        MaximumPowerTransmittedWEditFieldLabel  matlab.ui.control.Label
        MaximumPowerTransmittedWEditField  matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel    matlab.ui.control.Label
        FrequencyMHzEditField         matlab.ui.control.NumericEditField
        FarFieldThresholdmEditFieldLabel  matlab.ui.control.Label
        FarFieldThresholdmEditField   matlab.ui.control.NumericEditField
        ComputeButton                 matlab.ui.control.Button
        Panel2                        matlab.ui.container.Panel
        CloseXmEditFieldLabel         matlab.ui.control.Label
        CloseXmEditField              matlab.ui.control.NumericEditField
        FarXmEditFieldLabel           matlab.ui.control.Label
        FarXmEditField                matlab.ui.control.NumericEditField
        MinimumPowerReceivedmWEditFieldLabel  matlab.ui.control.Label
        MinimumumPowerReceivedmWEditField  matlab.ui.control.NumericEditField
        HPBWdegreesEditFieldLabel     matlab.ui.control.Label
        HPBWdegreesEditField          matlab.ui.control.NumericEditField
        MaximumAntennaApertureLengthmEditFieldLabel  matlab.ui.control.Label
        MaximumAntennaApertureLengthmEditField  matlab.ui.control.NumericEditField
        MaxDistancewithin3dBrangemEditFieldLabel  matlab.ui.control.Label
        MaxDistancewithin3dBrangemEditField  matlab.ui.control.NumericEditField
        UIAxes2D                      matlab.ui.control.UIAxes
        UIAxes3D                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        entranceWidth = 0;
        antenna2entrance = 0;
        antennaGainDBI = 0;
        inputPower = 0;
        frequency = 0;
        hpbwDegrees = 0;
        FFThreshold = 0;
        FFDomain;
        FFCurve;
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AntennaToEntrancemEditField
        function AntennaToEntrancemEditFieldValueChanged(app, event)
            app.antenna2entrance = app.AntennaToEntrancemEditField.Value;
        end

        % Value changed function: EntranceWidthmEditField
        function EntranceWidthmEditFieldValueChanged(app, event)
            app.entranceWidth = app.EntranceWidthmEditField.Value;
        end

        % Value changed function: AntennaGaindBiEditField
        function AntennaGaindBiEditFieldValueChanged(app, event)
            app.antennaGainDBI = app.AntennaGaindBiEditField.Value;
            app.hpbwDegrees = gain2BW(app.antennaGainDBI);
            app.HPBWdegreesEditField.Value = app.hpbwDegrees;
        end

        % Value changed function: MaximumPowerTransmittedWEditField
        function MaximumPowerTransmittedWEditFieldValueChanged(app, event)
            app.inputPower = app.MaximumPowerTransmittedWEditField.Value;
        end

        % Value changed function: FrequencyMHzEditField
        function FrequencyMHzEditFieldValueChanged(app, event)
            app.frequency = (app.FrequencyMHzEditField.Value) * 10^6;
        end

        % Value changed function: FarFieldThresholdmEditField
        function FarFieldThresholdmEditFieldValueChanged(app, event)
            app.FFThreshold = app.FarFieldThresholdmEditField.Value;
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            cla(app.UIAxes2D) 
            hold(app.UIAxes2D,'on')
            
            antennaGain = log2Linear(app.antennaGainDBI);
            antennaHPGain = log2Linear(app.antennaGainDBI-3);
            
            alpha = app.hpbwDegrees / 2;
            theta = 90 - alpha;
            thetaRadians = deg2rad(theta);
            slope = tan(thetaRadians);
            
            closeX = app.antenna2entrance / slope;
            farX = (app.entranceWidth + app.antenna2entrance) / slope;
            
            maximumDistance = sqrt((app.entranceWidth + app.antenna2entrance)^2 + farX^2);
            
            minimumPowerReceived = getPowerReceived(app.inputPower, antennaHPGain, maximumDistance, app.frequency);
            minimumPowerReceivedmW = minimumPowerReceived * 1e3;
                        
            sampling = 0.01 * (farX+1);
            x = [-farX-1: sampling: farX+1];
            
            a2eLine = app.antenna2entrance * ones(size(x));
            entranceWLine = (app.entranceWidth + app.antenna2entrance) * ones(size(x));
            negativeLine = x(1:find(x==0)) * -1 * slope;
            positiveLine = x(find(x==0):end) * slope;
            [app.FFDomain,app.FFCurve,maximumAperture] = getFFThresholdCurve(x,app.FFThreshold,app.frequency);
            
            plot(app.UIAxes2D,x,a2eLine);
            plot(app.UIAxes2D,x,entranceWLine);
            plot(app.UIAxes2D,x(1:find(x==0)),negativeLine);
            plot(app.UIAxes2D,x(find(x==0):end),positiveLine);
            plot(app.UIAxes2D,app.FFDomain,app.FFCurve)
            hold(app.UIAxes2D,'off');
            
            hold(app.UIAxes3D,'on');
            w = patch(app.UIAxes3D, [1 -1 -1 1], [1 1 -1 -1], [0 0 0 0], [1 1 -1 -1]);
            view(app.UIAxes3D,[1 1 1]) 
            direction = [0 1 0];
            rotate(w,direction,90)
            hold(app.UIAxes3D,'off');
            
            app.MaximumAntennaApertureLengthmEditField.Value = maximumAperture;
            app.MaxDistancewithin3dBrangemEditField.Value = maximumDistance;
            app.MinimumumPowerReceivedmWEditField.Value = minimumPowerReceivedmW;
            app.CloseXmEditField.Value = closeX;
            app.FarXmEditField.Value = farX;
        end

        % Button down function: UIAxes2D
        function UIAxes2DButtonDown(app, event)
            app.UIAxes2D.HandleVisibility = 'callback';
           
            cp = get(app.UIAxes,'CurrentPoint');
            x = cp(1,1);
            y = cp(1,2);
            
            distance = sqrt(x^2 + y^2);
            thetaRadians = atan(x/y);
            interpolatedGainDBI = interpolateGain(app.antennaGainDBI,deg2rad(app.hpbwDegrees),abs(thetaRadians));
            antennaGain = 10^(interpolatedGainDBI/10);
            powerReceived = getPowerReceived(app.inputPower, antennaGain, distance, app.frequency);
            
            if (abs(x)<app.FarFieldThresholdmEditField.Value)
                [~,~,idx]=unique(round(abs(app.FFDomain-abs(x))),'stable');
                yMax = app.FFCurve(idx==1)
                if (y<yMax)
                   powerReceived = 0;
                end
            end
            
            app.XmEditField.Value = x;
            app.YmEditField.Value = y;
            app.DistancetoAntennamEditField.Value = distance;
            app.PowerReceivedmWEditField.Value = powerReceived * 10^3;
            app.UIAxes2D.HandleVisibility = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.AntennaToEntrancemEditFieldLabel = uilabel(app.Panel);
            app.AntennaToEntrancemEditFieldLabel.Text = 'Antenna To Entrance (m)';
            app.AntennaToEntrancemEditField = uieditfield(app.Panel, 'numeric');
            app.AntennaToEntrancemEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaToEntrancemEditFieldValueChanged, true);
            app.EntranceWidthmEditFieldLabel = uilabel(app.Panel);
            app.EntranceWidthmEditFieldLabel.Text = 'Entrance Width (m)';
            app.EntranceWidthmEditField = uieditfield(app.Panel, 'numeric');
            app.EntranceWidthmEditField.ValueChangedFcn = createCallbackFcn(app, @EntranceWidthmEditFieldValueChanged, true);
            app.AntennaGaindBiEditFieldLabel = uilabel(app.Panel);
            app.AntennaGaindBiEditFieldLabel.Text = 'Antenna Gain (dBi)';
            app.AntennaGaindBiEditField = uieditfield(app.Panel, 'numeric');
            app.AntennaGaindBiEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaGaindBiEditFieldValueChanged, true);
            app.MaximumPowerTransmittedWEditFieldLabel = uilabel(app.Panel);
            app.MaximumPowerTransmittedWEditFieldLabel.Text = 'Maximum Power Transmitted (W)';
            app.MaximumPowerTransmittedWEditField = uieditfield(app.Panel, 'numeric');
            app.MaximumPowerTransmittedWEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPowerTransmittedWEditFieldValueChanged, true);
            app.FrequencyMHzEditFieldLabel = uilabel(app.Panel);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.Panel, 'numeric');
            app.FrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzEditFieldValueChanged, true);
            app.FarFieldThresholdmEditFieldLabel = uilabel(app.Panel);
            app.FarFieldThresholdmEditFieldLabel.Text = 'Far Field Threshold (m)';
            app.FarFieldThresholdmEditField = uieditfield(app.Panel, 'numeric');
            app.FarFieldThresholdmEditField.ValueChangedFcn = createCallbackFcn(app, @FarFieldThresholdmEditFieldValueChanged, true);
            app.ComputeButton = uibutton(app.Panel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.CloseXmEditFieldLabel = uilabel(app.Panel2);
            app.CloseXmEditFieldLabel.Text = 'Close X (m)';
            app.CloseXmEditField = uieditfield(app.Panel2, 'numeric');
            app.FarXmEditFieldLabel = uilabel(app.Panel2);
            app.FarXmEditFieldLabel.Text = 'Far X (m)';
            app.FarXmEditField = uieditfield(app.Panel2, 'numeric');
            app.MinimumPowerReceivedmWEditFieldLabel = uilabel(app.Panel2);
            app.MinimumPowerReceivedmWEditFieldLabel.Text = 'Minimum Power Received (mW)';
            app.MinimumumPowerReceivedmWEditField = uieditfield(app.Panel2, 'numeric');
            app.HPBWdegreesEditFieldLabel = uilabel(app.Panel2);
            app.HPBWdegreesEditFieldLabel.Text = 'HPBW (degrees)';
            app.HPBWdegreesEditField = uieditfield(app.Panel2, 'numeric');
            app.MaximumAntennaApertureLengthmEditFieldLabel = uilabel(app.Panel2);
            app.MaximumAntennaApertureLengthmEditFieldLabel.Text = 'Maximum Antenna Aperture Length (m)';
            app.MaximumAntennaApertureLengthmEditField = uieditfield(app.Panel2, 'numeric');
            app.MaxDistancewithin3dBrangemEditFieldLabel = uilabel(app.Panel2);
            app.MaxDistancewithin3dBrangemEditFieldLabel.Text = 'Max Distance within 3dB range (m)';
            app.MaxDistancewithin3dBrangemEditField = uieditfield(app.Panel2, 'numeric');
            app.UIAxes2D = uiaxes(app.UIFigure);
            title(app.UIAxes2D, 'Entrance Model Illustration')
            xlabel(app.UIAxes2D, 'X')
            ylabel(app.UIAxes2D, 'Y')
            zlabel(app.UIAxes2D, 'Z')
            app.UIAxes2D.ButtonDownFcn = createCallbackFcn(app, @UIAxes2DButtonDown, true);
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, '3D Illustration')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EntranceModelApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end